classdef assignment_2_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AutoTab                        matlab.ui.container.Tab
        DescriptionTextArea_6Label     matlab.ui.control.Label
        DescriptionTextArea_6          matlab.ui.control.TextArea
        SetAutoButton                  matlab.ui.control.Button
        ManualTab                      matlab.ui.container.Tab
        DescriptionTextArea_5Label     matlab.ui.control.Label
        DescriptionTextArea_5          matlab.ui.control.TextArea
        CheckConnectionButton          matlab.ui.control.Button
        SetManualButton                matlab.ui.control.Button
        JogTab                         matlab.ui.container.Tab
        DescriptionTextArea_2Label     matlab.ui.control.Label
        DescriptionTextArea_2          matlab.ui.control.TextArea
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        DesiredEndEffectorPositionLabel  matlab.ui.control.Label
        SetJoggingButton               matlab.ui.control.Button
        XEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        RollEditFieldLabel             matlab.ui.control.Label
        RollEditField                  matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        RedThreshholdEditFieldLabel    matlab.ui.control.Label
        camRed                         matlab.ui.control.NumericEditField
        GreenThreshholdEditFieldLabel  matlab.ui.control.Label
        camGreen                       matlab.ui.control.NumericEditField
        BlueThreshholdEditFieldLabel   matlab.ui.control.Label
        camBlue                        matlab.ui.control.NumericEditField
        BlobSizeEditFieldLabel         matlab.ui.control.Label
        EndEffectorPositionLabel_2     matlab.ui.control.Label
        camBlob                        matlab.ui.control.NumericEditField
        CameraValueLabel               matlab.ui.control.Label
        TestButton                     matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        CurrentModeEditFieldLabel      matlab.ui.control.Label
        currentMode                    matlab.ui.control.EditField
    end

    
    properties (Access = private)
        mode; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            rgbTrack(app.camRed.Value, app.camGreen.Value, app.camBlue.Value, app.camBlob.Value);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clearWebcam;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.camRed.Value = 0.255;
            app.camGreen.Value = 0.25;
            app.camBlue.Value = 0.02;
            app.camBlob.Value = 300;
        end

        % Button pushed function: SetAutoButton
        function SetAutoButtonPushed(app, event)
            app.mode = 1;
            app.currentMode.Value = 'Auto'
        end

        % Button pushed function: SetManualButton
        function SetManualButtonPushed(app, event)
            app.mode = 2;
            app.currentMode.Value = 'Manual'
        end

        % Button pushed function: SetJoggingButton
        function SetJoggingButtonPushed(app, event)
            app.mode = 3;
            app.currentMode.Value = 'Jogging'
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            switch (app.mode)
                case 1
                    
                case 2
                    
                case 3
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.DescriptionTextArea_6Label = uilabel(app.AutoTab);
            app.DescriptionTextArea_6Label.Text = 'Description';
            app.DescriptionTextArea_6 = uitextarea(app.AutoTab);
            app.DescriptionTextArea_6.Value = {'The robot will detect,  move toward the desired position and perform cleaning action without requiring further user input.'};
            app.SetAutoButton = uibutton(app.AutoTab, 'push');
            app.SetAutoButton.ButtonPushedFcn = createCallbackFcn(app, @SetAutoButtonPushed, true);
            app.SetAutoButton.Text = 'Set Auto';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.DescriptionTextArea_5Label = uilabel(app.ManualTab);
            app.DescriptionTextArea_5Label.Text = 'Description';
            app.DescriptionTextArea_5 = uitextarea(app.ManualTab);
            app.DescriptionTextArea_5.Value = {'User can manually control the robot using a controler. '};
            app.CheckConnectionButton = uibutton(app.ManualTab, 'push');
            app.CheckConnectionButton.Text = 'Check Connection';
            app.SetManualButton = uibutton(app.ManualTab, 'push');
            app.SetManualButton.ButtonPushedFcn = createCallbackFcn(app, @SetManualButtonPushed, true);
            app.SetManualButton.Text = 'Set Manual';
            app.JogTab = uitab(app.TabGroup);
            app.JogTab.Title = 'Jog';
            app.DescriptionTextArea_2Label = uilabel(app.JogTab);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionTextArea_2 = uitextarea(app.JogTab);
            app.DescriptionTextArea_2.Value = {'Assign the desired cordinate and tell the robot to move toward that position.'};
            app.XEditFieldLabel = uilabel(app.JogTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.JogTab, 'numeric');
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.JogTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.JogTab, 'numeric');
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.JogTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.JogTab, 'numeric');
            app.ZEditField.Editable = 'off';
            app.DesiredEndEffectorPositionLabel = uilabel(app.JogTab);
            app.DesiredEndEffectorPositionLabel.Text = 'Desired End Effector Position';
            app.SetJoggingButton = uibutton(app.JogTab, 'push');
            app.SetJoggingButton.ButtonPushedFcn = createCallbackFcn(app, @SetJoggingButtonPushed, true);
            app.SetJoggingButton.Text = 'Set Jogging';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.Editable = 'off';
            app.RedThreshholdEditFieldLabel = uilabel(app.UIFigure);
            app.RedThreshholdEditFieldLabel.Text = 'Red Threshhold';
            app.camRed = uieditfield(app.UIFigure, 'numeric');
            app.camRed.Limits = [0 0.3];
            app.camRed.Value = 0.255;
            app.GreenThreshholdEditFieldLabel = uilabel(app.UIFigure);
            app.GreenThreshholdEditFieldLabel.Text = 'Green Threshhold';
            app.camGreen = uieditfield(app.UIFigure, 'numeric');
            app.camGreen.Limits = [0 0.3];
            app.camGreen.Value = 0.25;
            app.BlueThreshholdEditFieldLabel = uilabel(app.UIFigure);
            app.BlueThreshholdEditFieldLabel.Text = 'Blue Threshhold';
            app.camBlue = uieditfield(app.UIFigure, 'numeric');
            app.camBlue.Limits = [0 0.3];
            app.camBlue.Value = 0.02;
            app.BlobSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlobSizeEditFieldLabel.Text = 'Blob Size';
            app.camBlob = uieditfield(app.UIFigure, 'numeric');
            app.camBlob.Limits = [0 Inf];
            app.camBlob.Value = 300;
            app.CameraValueLabel = uilabel(app.UIFigure);
            app.CameraValueLabel.Text = 'Camera Value';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'Stop Button.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Start.jpg';
            app.Button_2.Text = '';
            app.EndEffectorPositionLabel_2 = uilabel(app.UIFigure);
            app.EndEffectorPositionLabel_2.Text = 'End Effector Position';
            app.CurrentModeEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentModeEditFieldLabel.Text = 'Current Mode';
            app.currentMode = uieditfield(app.UIFigure, 'text');
            app.currentMode.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment_2_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end